classdef RayTracing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SetupTab                  matlab.ui.container.Tab
        WallsPanel                matlab.ui.container.Panel
        AddWallButton             matlab.ui.control.Button
        DeleteWallButton          matlab.ui.control.Button
        ListBox                   matlab.ui.control.ListBox
        X1EditFieldLabel          matlab.ui.control.Label
        X1EditField               matlab.ui.control.NumericEditField
        X2EditFieldLabel          matlab.ui.control.Label
        X2EditField               matlab.ui.control.NumericEditField
        Y1EditFieldLabel          matlab.ui.control.Label
        Y1EditField               matlab.ui.control.NumericEditField
        Y2EditFieldLabel          matlab.ui.control.Label
        Y2EditField               matlab.ui.control.NumericEditField
        DropDown                  matlab.ui.control.DropDown
        EEditFieldLabel           matlab.ui.control.Label
        EEditField                matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        SourcePanel               matlab.ui.container.Panel
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        AddSourceButton           matlab.ui.control.Button
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        ListBox_2                 matlab.ui.control.ListBox
        DeleteSourceButton        matlab.ui.control.Button
        CalculateButton           matlab.ui.control.Button
        xMaxyMaxButtonGroup       matlab.ui.container.ButtonGroup
        AutoButton                matlab.ui.control.RadioButton
        ManualButton              matlab.ui.control.RadioButton
        xMaxEditFieldLabel        matlab.ui.control.Label
        xMaxEditField             matlab.ui.control.NumericEditField
        yMaxEditFieldLabel        matlab.ui.control.Label
        yMaxEditField             matlab.ui.control.NumericEditField
        PrecistionPanel           matlab.ui.container.Panel
        StepEditFieldLabel        matlab.ui.control.Label
        StepEditField             matlab.ui.control.NumericEditField
        BoxesTab                  matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        BorderPanel               matlab.ui.container.Panel
        AddBoxButton              matlab.ui.control.Button
        DeleteBoxButton           matlab.ui.control.Button
        ListBox_3                 matlab.ui.control.ListBox
        X1EditField_2Label        matlab.ui.control.Label
        X1EditField_2             matlab.ui.control.NumericEditField
        X2EditField_2Label        matlab.ui.control.Label
        X2EditField_2             matlab.ui.control.NumericEditField
        Y1EditField_2Label        matlab.ui.control.Label
        Y1EditField_2             matlab.ui.control.NumericEditField
        Y2EditField_2Label        matlab.ui.control.Label
        Y2EditField_2             matlab.ui.control.NumericEditField
        ReflectPanel              matlab.ui.container.Panel
        AddBoxButton_2            matlab.ui.control.Button
        DeleteBoxButton_2         matlab.ui.control.Button
        ListBox_4                 matlab.ui.control.ListBox
        X1EditField_3Label        matlab.ui.control.Label
        X1EditField_3             matlab.ui.control.NumericEditField
        X2EditField_3Label        matlab.ui.control.Label
        X2EditField_3             matlab.ui.control.NumericEditField
        Y1EditField_3Label        matlab.ui.control.Label
        Y1EditField_3             matlab.ui.control.NumericEditField
        Y2EditField_3Label        matlab.ui.control.Label
        Y2EditField_3             matlab.ui.control.NumericEditField
        ShowwallsCheckBox         matlab.ui.control.CheckBox
        SavesTab                  matlab.ui.container.Tab
        SavecurrentlayoutPanel    matlab.ui.container.Panel
        SaveButton                matlab.ui.control.Button
        FileNameEditField_2Label  matlab.ui.control.Label
        FileNameEditField_2       matlab.ui.control.EditField
        LoadlayoutPanel           matlab.ui.container.Panel
        FileNameEditFieldLabel    matlab.ui.control.Label
        FileNameEditField         matlab.ui.control.EditField
        LoadButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        walls = Wall.empty;
        antennes = zeros(0,2);
        borderBox = zeros(0,4);
        reflectionBox = zeros(0,4);
    end
    
    methods (Access = private)
        
        function [] = redrawMap(app,indexWall, indexSource)
            app.UIAxes.cla;
            hold(app.UIAxes, 'on');
            for k=1:size(app.walls)
                if(k==indexWall)
                    plot(app.UIAxes, [app.walls(k).X1,app.walls(k).X2],[app.walls(k).Y1,app.walls(k).Y2],'g')
                else
                    plot(app.UIAxes, [app.walls(k).X1,app.walls(k).X2],[app.walls(k).Y1,app.walls(k).Y2],'k')
                end
            end
            for k=1:size(app.antennes)
                if(k==indexSource)
                    scatter(app.UIAxes, app.antennes(k,1),app.antennes(k,2), 'm')
                else
                    scatter(app.UIAxes, app.antennes(k,1),app.antennes(k,2), 'b')
                end
            end
        end
        
        function [] = redrawMap2(app)
            app.UIAxes_2.cla;
            hold(app.UIAxes_2, 'on');
            
            for k=1:size(app.borderBox)
                plot(app.UIAxes_2, [app.borderBox(k,1),app.borderBox(k,3)],[app.borderBox(k,2),app.borderBox(k,2)],'b')
                plot(app.UIAxes_2, [app.borderBox(k,3),app.borderBox(k,3)],[app.borderBox(k,2),app.borderBox(k,4)],'b')
                plot(app.UIAxes_2, [app.borderBox(k,3),app.borderBox(k,1)],[app.borderBox(k,4),app.borderBox(k,4)],'b')
                plot(app.UIAxes_2, [app.borderBox(k,1),app.borderBox(k,1)],[app.borderBox(k,4),app.borderBox(k,2)],'b')
            end
            for k=1:size(app.reflectionBox)
                plot(app.UIAxes_2, [app.reflectionBox(k,1),app.reflectionBox(k,3)],[app.reflectionBox(k,2),app.reflectionBox(k,2)],'r')
                plot(app.UIAxes_2, [app.reflectionBox(k,3),app.reflectionBox(k,3)],[app.reflectionBox(k,2),app.reflectionBox(k,4)],'r')
                plot(app.UIAxes_2, [app.reflectionBox(k,3),app.reflectionBox(k,1)],[app.reflectionBox(k,4),app.reflectionBox(k,4)],'r')
                plot(app.UIAxes_2, [app.reflectionBox(k,1),app.reflectionBox(k,1)],[app.reflectionBox(k,4),app.reflectionBox(k,2)],'r')
            end
            if app.ShowwallsCheckBox.Value
                for k=1:size(app.walls)
                        plot(app.UIAxes_2, [app.walls(k).X1,app.walls(k).X2],[app.walls(k).Y1,app.walls(k).Y2],'k')
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddWallButton
        function AddWallButtonPushed(app, event)
            epsilon = [4.6*PrjCst.EPS_0, 5.0*PrjCst.EPS_0 , 2.25*PrjCst.EPS_0];
            sigma = [0.02, 0.014, 0.04];
            x1 = app.X1EditField.Value; y1 = app.Y1EditField.Value;
            x2 = app.X2EditField.Value; y2 = app.Y2EditField.Value;
            epsi = epsilon(str2double(app.DropDown.Value(1)));
            sig = sigma(str2double(app.DropDown.Value(1)));
            thick = app.EEditField.Value;
            
            newWall = Wall(x1,y1,x2,y2,epsi,sig,thick);
            
            app.walls(end+1,1) = newWall;
            app.ListBox.Items=cat(2,app.ListBox.Items,sprintf('Wall %d',size(app.walls,1)));
            redrawMap(app,0,0);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = str2double(app.ListBox.Value(6:end));
            redrawMap(app, value,0);
            
        end

        % Button pushed function: DeleteWallButton
        function DeleteWallButtonPushed(app, event)
            value = str2double(app.ListBox.Value(6:end));
            app.walls=app.walls([1:value-1,value+1:end]);
            app.ListBox.Items=app.ListBox.Items(1:end-1);
            redrawMap(app,0,0);
        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            app.antennes(end+1,:)=[app.XEditField.Value,app.YEditField.Value];
            app.ListBox_2.Items=cat(2,app.ListBox_2.Items,sprintf('Source %d',size(app.antennes,1)));
            redrawMap(app,0,0);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            val = app.xMaxyMaxButtonGroup.SelectedObject.Position;
            if val(2)==21
                maxX=0;
                maxY=0;
                for w = 1:length(app.walls)
                    maxX=max([maxX, app.walls(w).X1,app.walls(w).X2]);
                    maxY=max([maxY, app.walls(w).Y1,app.walls(w).Y2]);
                end
            else
                maxX=app.xMaxEditField.Value;
                maxY=app.yMaxEditField.Value;
            end

            
            Power= mainRay(maxX,maxY,app.walls, app.antennes, app.borderBox, app.reflectionBox, app.StepEditField.Value, app.UIFigure);
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = str2double(app.ListBox_2.Value(8:end));
            redrawMap(app,0, value);
            
        end

        % Button pushed function: DeleteSourceButton
        function DeleteSourceButtonPushed(app, event)
            value = str2double(app.ListBox_2.Value(8:end));
            app.antennes=app.antennes([1:value-1,value+1:end],:);
            app.ListBox_2.Items=app.ListBox_2.Items(1:end-1);
            redrawMap(app,0,0);
        end

        % Selection changed function: xMaxyMaxButtonGroup
        function xMaxyMaxButtonGroupSelectionChanged(app, event)
            val = app.xMaxyMaxButtonGroup.SelectedObject.Position;
            if val(2)==21
                set(app.xMaxEditField,'enable','off');
                set(app.xMaxEditField,'editable','off');
                set(app.yMaxEditField,'enable','off');
                set(app.yMaxEditField,'editable','off');
            else
                set(app.xMaxEditField,'enable','on');
                set(app.xMaxEditField,'editable','on');
                set(app.yMaxEditField,'enable','on');
                set(app.yMaxEditField,'editable','on');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveLayout(app.FileNameEditField_2.Value, app.walls, app.antennes, app.reflectionBox, app.borderBox);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.walls, app.antennes, app.reflectionBox, app.borderBox] = loadLayout(app.FileNameEditField.Value);
            redrawMap(app,0, 0);
        end

        % Value changed function: ShowwallsCheckBox
        function ShowwallsCheckBoxValueChanged(app, event)
            redrawMap2(app);
            
        end

        % Button pushed function: AddBoxButton
        function AddBoxButtonPushed(app, event)
            app.borderBox(end+1,:)=[app.X1EditField_2.Value,app.Y1EditField_2.Value,app.X2EditField_2.Value,app.Y2EditField_2.Value];
            app.ListBox_3.Items=cat(2,app.ListBox_3.Items,sprintf('Box %d',size(app.borderBox,1)));
            redrawMap2(app);
        end

        % Button pushed function: DeleteBoxButton
        function DeleteBoxButtonPushed(app, event)
            value = str2double(app.ListBox_3.Value(5:end));
            app.borderBox=app.borderBox([1:value-1,value+1:end],:);
            app.ListBox_3.Items=app.ListBox_3.Items(1:end-1);
            redrawMap2(app);
        end

        % Button pushed function: AddBoxButton_2
        function AddBoxButton_2Pushed(app, event)
            app.reflectionBox(end+1,:)=[app.X1EditField_3.Value,app.Y1EditField_3.Value,app.X2EditField_3.Value,app.Y2EditField_3.Value];
            app.ListBox_4.Items=cat(2,app.ListBox_4.Items,sprintf('Box %d',size(app.reflectionBox,1)));
            redrawMap2(app);
        end

        % Button pushed function: DeleteBoxButton_2
        function DeleteBoxButton_2Pushed(app, event)
            value = str2double(app.ListBox_4.Value(5:end));
            app.reflectionBox=app.reflectionBox([1:value-1,value+1:end],:);
            app.ListBox_4.Items=app.ListBox_4.Items(1:end-1);
            redrawMap2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.WallsPanel = uipanel(app.SetupTab);
            app.WallsPanel.Title = 'Walls';
            app.AddWallButton = uibutton(app.WallsPanel, 'push');
            app.AddWallButton.ButtonPushedFcn = createCallbackFcn(app, @AddWallButtonPushed, true);
            app.AddWallButton.Text = 'Add Wall';
            app.DeleteWallButton = uibutton(app.WallsPanel, 'push');
            app.DeleteWallButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWallButtonPushed, true);
            app.DeleteWallButton.Text = 'Delete Wall';
            app.ListBox = uilistbox(app.WallsPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.X1EditFieldLabel = uilabel(app.WallsPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.WallsPanel, 'numeric');
            app.X2EditFieldLabel = uilabel(app.WallsPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.WallsPanel, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.WallsPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.WallsPanel, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.WallsPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.WallsPanel, 'numeric');
            app.DropDown = uidropdown(app.WallsPanel);
            app.DropDown.Items = {'1 Brique', '2 Béton', '3 Cloison'};
            app.DropDown.Value = '1 Brique';
            app.EEditFieldLabel = uilabel(app.WallsPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.WallsPanel, 'numeric');
            app.EEditField.Value = 0.1;
            app.UIAxes = uiaxes(app.SetupTab);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SourcePanel = uipanel(app.SetupTab);
            app.SourcePanel.Title = 'Source';
            app.YEditFieldLabel = uilabel(app.SourcePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SourcePanel, 'numeric');
            app.AddSourceButton = uibutton(app.SourcePanel, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add Source';
            app.XEditFieldLabel = uilabel(app.SourcePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SourcePanel, 'numeric');
            app.ListBox_2 = uilistbox(app.SourcePanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.DeleteSourceButton = uibutton(app.SourcePanel, 'push');
            app.DeleteSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSourceButtonPushed, true);
            app.DeleteSourceButton.Text = 'Delete Source';
            app.CalculateButton = uibutton(app.SetupTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.xMaxyMaxButtonGroup = uibuttongroup(app.SetupTab);
            app.xMaxyMaxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xMaxyMaxButtonGroupSelectionChanged, true);
            app.xMaxyMaxButtonGroup.Title = 'xMax & yMax';
            app.AutoButton = uiradiobutton(app.xMaxyMaxButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.xMaxyMaxButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.xMaxEditFieldLabel = uilabel(app.SetupTab);
            app.xMaxEditFieldLabel.Text = 'xMax';
            app.xMaxEditField = uieditfield(app.SetupTab, 'numeric');
            app.xMaxEditField.Editable = 'off';
            app.xMaxEditField.Enable = 'off';
            app.yMaxEditFieldLabel = uilabel(app.SetupTab);
            app.yMaxEditFieldLabel.Text = 'yMax';
            app.yMaxEditField = uieditfield(app.SetupTab, 'numeric');
            app.yMaxEditField.Editable = 'off';
            app.yMaxEditField.Enable = 'off';
            app.PrecistionPanel = uipanel(app.SetupTab);
            app.PrecistionPanel.Title = 'Precistion';
            app.StepEditFieldLabel = uilabel(app.PrecistionPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.PrecistionPanel, 'numeric');
            app.StepEditField.Value = 0.5;
            app.BoxesTab = uitab(app.TabGroup);
            app.BoxesTab.Title = 'Boxes';
            app.UIAxes_2 = uiaxes(app.BoxesTab);
            title(app.UIAxes_2, 'Map')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.BorderPanel = uipanel(app.BoxesTab);
            app.BorderPanel.Title = 'Border';
            app.AddBoxButton = uibutton(app.BorderPanel, 'push');
            app.AddBoxButton.ButtonPushedFcn = createCallbackFcn(app, @AddBoxButtonPushed, true);
            app.AddBoxButton.Text = 'Add Box';
            app.DeleteBoxButton = uibutton(app.BorderPanel, 'push');
            app.DeleteBoxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBoxButtonPushed, true);
            app.DeleteBoxButton.Text = 'Delete Box';
            app.ListBox_3 = uilistbox(app.BorderPanel);
            app.ListBox_3.Items = {};
            app.ListBox_3.Value = {};
            app.X1EditField_2Label = uilabel(app.BorderPanel);
            app.X1EditField_2Label.Text = 'X1';
            app.X1EditField_2 = uieditfield(app.BorderPanel, 'numeric');
            app.X2EditField_2Label = uilabel(app.BorderPanel);
            app.X2EditField_2Label.Text = 'X2';
            app.X2EditField_2 = uieditfield(app.BorderPanel, 'numeric');
            app.Y1EditField_2Label = uilabel(app.BorderPanel);
            app.Y1EditField_2Label.Text = 'Y1';
            app.Y1EditField_2 = uieditfield(app.BorderPanel, 'numeric');
            app.Y2EditField_2Label = uilabel(app.BorderPanel);
            app.Y2EditField_2Label.Text = 'Y2';
            app.Y2EditField_2 = uieditfield(app.BorderPanel, 'numeric');
            app.ReflectPanel = uipanel(app.BoxesTab);
            app.ReflectPanel.Title = 'Reflect';
            app.AddBoxButton_2 = uibutton(app.ReflectPanel, 'push');
            app.AddBoxButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddBoxButton_2Pushed, true);
            app.AddBoxButton_2.Text = 'Add Box';
            app.DeleteBoxButton_2 = uibutton(app.ReflectPanel, 'push');
            app.DeleteBoxButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteBoxButton_2Pushed, true);
            app.DeleteBoxButton_2.Text = 'Delete Box';
            app.ListBox_4 = uilistbox(app.ReflectPanel);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.X1EditField_3Label = uilabel(app.ReflectPanel);
            app.X1EditField_3Label.Text = 'X1';
            app.X1EditField_3 = uieditfield(app.ReflectPanel, 'numeric');
            app.X2EditField_3Label = uilabel(app.ReflectPanel);
            app.X2EditField_3Label.Text = 'X2';
            app.X2EditField_3 = uieditfield(app.ReflectPanel, 'numeric');
            app.Y1EditField_3Label = uilabel(app.ReflectPanel);
            app.Y1EditField_3Label.Text = 'Y1';
            app.Y1EditField_3 = uieditfield(app.ReflectPanel, 'numeric');
            app.Y2EditField_3Label = uilabel(app.ReflectPanel);
            app.Y2EditField_3Label.Text = 'Y2';
            app.Y2EditField_3 = uieditfield(app.ReflectPanel, 'numeric');
            app.ShowwallsCheckBox = uicheckbox(app.BoxesTab);
            app.ShowwallsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowwallsCheckBoxValueChanged, true);
            app.ShowwallsCheckBox.Text = 'Show walls';
            app.SavesTab = uitab(app.TabGroup);
            app.SavesTab.Title = 'Saves';
            app.SavecurrentlayoutPanel = uipanel(app.SavesTab);
            app.SavecurrentlayoutPanel.Title = 'Save current layout';
            app.SaveButton = uibutton(app.SavecurrentlayoutPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditField_2Label = uilabel(app.SavecurrentlayoutPanel);
            app.FileNameEditField_2Label.Text = 'FileName';
            app.FileNameEditField_2 = uieditfield(app.SavecurrentlayoutPanel, 'text');
            app.FileNameEditField_2.Value = 'save1';
            app.LoadlayoutPanel = uipanel(app.SavesTab);
            app.LoadlayoutPanel.Title = 'Load layout';
            app.FileNameEditFieldLabel = uilabel(app.LoadlayoutPanel);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.LoadlayoutPanel, 'text');
            app.FileNameEditField.Value = 'save1';
            app.LoadButton = uibutton(app.LoadlayoutPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RayTracing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end